classdef PRoyectofinal_OA_LJ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ObtencinDeDatosTab              matlab.ui.container.Tab
        PuertoComEditFieldLabel         matlab.ui.control.Label
        PuertoComEditField              matlab.ui.control.EditField
        ConectarButton                  matlab.ui.control.Button
        TiempodesenialmuestradasEditFieldLabel  matlab.ui.control.Label
        TiempodesenialmuestradasEditField  matlab.ui.control.NumericEditField
        CargarButton                    matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        DTiempoTab                      matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        DFrecuenciaTab                  matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        DiseodeFiltroTab                matlab.ui.container.Tab
        FrecunciadecorteHzlowEditFieldLabel  matlab.ui.control.Label
        FrecunciadecorteHzlowEditField  matlab.ui.control.NumericEditField
        VisualizarfiltroButton          matlab.ui.control.Button
        FrecunciadecorteHzhighEditFieldLabel  matlab.ui.control.Label
        FrecunciadecorteHzhighEditField  matlab.ui.control.NumericEditField
        FiltrosDropDownLabel            matlab.ui.control.Label
        FiltrosDropDown                 matlab.ui.control.DropDown
        FormadelfiltroSwitchLabel       matlab.ui.control.Label
        FormadelfiltroSwitch            matlab.ui.control.RockerSwitch
        OrdendefiltroEditFieldLabel     matlab.ui.control.Label
        OrdendefiltroEditField          matlab.ui.control.NumericEditField
        EjeYButtonGroup                 matlab.ui.container.ButtonGroup
        dbButton                        matlab.ui.control.RadioButton
        NodbButton                      matlab.ui.control.RadioButton
        EnviarFiltroButton              matlab.ui.control.Button
        AplicarFiltroButton             matlab.ui.control.Button
        TipodeFiltroDropDownLabel       matlab.ui.control.Label
        TipodeFiltroDropDown            matlab.ui.control.DropDown
        ConexionaTivaCheckBox           matlab.ui.control.CheckBox
        Ordenmaximodeenvioalativaes4Label  matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        FiltroTab                       matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        SealconfiltroTab                matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        EffectosButtonGroup             matlab.ui.container.ButtonGroup
        ningunoButton                   matlab.ui.control.RadioButton
        EchoButton                      matlab.ui.control.RadioButton
        algoButton                      matlab.ui.control.RadioButton
        ExportaDatosTab                 matlab.ui.container.Tab
        GuardarSealButton               matlab.ui.control.Button
        Propiedadesparaguardar___________Label  matlab.ui.control.Label
        VisualizarSealaguardarButton    matlab.ui.control.Button
        ExportardatosdelfiltroButton    matlab.ui.control.Button
        ecuacionLabel                   matlab.ui.control.Label
        TabGroup4                       matlab.ui.container.TabGroup
        SealFiltradaTab                 matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        SealnoFiltradaTab               matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Puerto_serial
        tiempo_de_senial
        senial
%         tab 2
        formadefiltros
        filtros
        tiposdefiltros
        frecuanciadecorte
        orden
        db
        Wn
        Wn2
        fc
        senial_filtrada
        h
        w
        H
        W
%       sent data 
        numerador 
        denominador 
        valordeFir1
        
        senialconf 
        senialsinf
               
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            global fs;
            tiva = serialport(app.PuertoComEditField.Value, 1000000); %el puerto puede variar entre computadores
            %frecuecia de muestreo en Hz.
            fs = 10000;
            % vectores de datos 
            N = fs*app.TiempodesenialmuestradasEditField.Value;
            app.senial = zeros(1,N);
            n = 1:N;
            flush(tiva);
            for i=n
            app.senial(i) = str2double(readline(tiva));
            end 
            clear tiva
            plot(app.UIAxes,n,app.senial)
            X=fft(app.senial);
            plot(app.UIAxes3,n,X)
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            global file
            global senial;
            global fs;
            [file,path] = uigetfile("*.mat*");%Ubica un directorio y logra usar cualquier tipo de archivo
            addpath(path);
           % data=strcat(file,'.mat');
            load(file,'fs');
            %fs = senial;
            load(file,'senial');
            app.senial = senial;
            n=length(app.senial);
            N = 1:n;
            plot(app.UIAxes,N,app.senial);
            X=fft(app.senial);
            plot(app.UIAxes3,N,X);
        end

        % Value changed function: FiltrosDropDown
        function FiltrosDropDownValueChanged(app, event)
            if(strcmp(app.FiltrosDropDown.Value,"high"))
                app.FrecunciadecorteHzlowEditField.Enable=false;
            else 
                app.FrecunciadecorteHzlowEditField.Enable=true;
            end
            if(strcmp(app.FiltrosDropDown.Value,"bandpass"))
                app.FrecunciadecorteHzhighEditField.Enable=true;
                app.FrecunciadecorteHzlowEditField.Enable=true;
            end
            if(strcmp(app.FiltrosDropDown.Value,"low"))
                app.FrecunciadecorteHzhighEditField.Enable=false;
            else 
                app.FrecunciadecorteHzhighEditField.Enable=true;
            end
        end

        % Button pushed function: VisualizarfiltroButton
        function boton_del_aplicacion(app, event)
            global a;
            global b;
            global c;
            global fs;
%___________________________       selector en iir      ____________________________________________
            if(strcmp(app.FormadelfiltroSwitch.Value,"IIR"))
%**********************************      butter     ************************************************   
               if(strcmp(app.TipodeFiltroDropDown.Value,"Butterwoth"))
%___________________________hacer iir en modo high__________________________________________________
                
                    if(strcmp(app.FiltrosDropDown.Value,"high"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        [a,b]=butter((app.OrdendefiltroEditField.Value),(app.Wn),("high"));
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer iir en modo Bandpass______________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"bandpass"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=butter((app.OrdendefiltroEditField.Value),[app.Wn2 app.Wn],"bandpass");
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer iir en modo stopband _____________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"Stopband"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=butter((app.OrdendefiltroEditField.Value),[app.Wn2 app.Wn],"stop");
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer iir en modo low___________________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"low"))
                        app.Wn=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=butter((app.OrdendefiltroEditField.Value),(app.Wn),("low"));
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)))); 
                        end
                    end
               end 
%*******************************      chebychefe    ************************************************ 
               if(strcmp(app.TipodeFiltroDropDown.Value,"Chebyshev"))
%___________________________hacer iir en modo high__________________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"high"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        [a,b]=cheby1(app.OrdendefiltroEditField.Value,1,(app.Wn),("high"));      
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer iir en modo Bandpass______________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"bandpass"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;                       
                        [a,b]=cheby1((app.OrdendefiltroEditField.Value),1,[app.Wn2 app.Wn],"bandpass");
                        
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer iir en modo Stopband______________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"Stopband"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;                       
                        [a,b]=cheby1((app.OrdendefiltroEditField.Value),1,[app.Wn2 app.Wn],"stop");
                        
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer fir en modo low___________________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"low"))
                        app.Wn=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=cheby1((app.OrdendefiltroEditField.Value),1,(app.Wn),("low"));
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)))); 
                        end
                    end
               end
               
%*************************************     ellip      **********************************************               
               if(strcmp(app.TipodeFiltroDropDown.Value,"Elliptico"))
%___________________________hacer fir en modo high__________________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"high"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        
                        
                        [a,b]=ellip(app.OrdendefiltroEditField.Value,(1),(40),(app.Wn),("high"));
                        
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer fir en modo Bandpass______________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"bandpass"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=ellip(app.OrdendefiltroEditField.Value,(0.1),(40),[app.Wn2 app.Wn],"bandpass");
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer fir en modo stop______________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"Stopband"))
                        app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                        app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=ellip(app.OrdendefiltroEditField.Value,(0.1),(40),[app.Wn2 app.Wn],"stop");
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                        end
                    end
%___________________________hacer fir en modo low___________________________________________________
                    if(strcmp(app.FiltrosDropDown.Value,"low"))
                        app.Wn=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                        [a,b]=ellip(app.OrdendefiltroEditField.Value,(1),(40),(app.Wn),("low"));
                        [app.h,app.w]=freqz(a,b);
                        if (app.NodbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                        end
                        if  (app.dbButton.Value)
                            plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)))); 
                        end
                    end
               end              
            end % end de if iir
            
%___________________________       selector en fir      ____________________________________________
            if(strcmp(app.FormadelfiltroSwitch.Value,"FIR"))
%___________________________hacer fir en modo high__________________________________________________
                if(strcmp(app.FiltrosDropDown.Value,"high"))
                    app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                    c=fir1((app.OrdendefiltroEditField.Value),(app.Wn),("high"));
                    [app.h,app.w]=freqz(c,1);
                    if (app.NodbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                    end
                    if (app.dbButton.Value)
                        plot(app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)))); 
                    end
                end
%___________________________hacer fir en modo bandpas_______________________________________________ 
                if(strcmp(app.FiltrosDropDown.Value,"bandpass"))
                    app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                    app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                    c=fir1((app.OrdendefiltroEditField.Value),[app.Wn2 app.Wn],"bandpass");
                    [app.h,app.w]=freqz(c,1,512,"whole");
                    if (app.NodbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                    end
                    if (app.dbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                    end
                end
%___________________________hacer fir en modo stop_______________________________________________ 
                if(strcmp(app.FiltrosDropDown.Value,"Stopband"))
                    app.Wn=(app.FrecunciadecorteHzhighEditField.Value)*2/fs;
                    app.Wn2=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                    c=fir1((app.OrdendefiltroEditField.Value),[app.Wn2 app.Wn],"stop");
                    [app.h,app.w]=freqz(c,1,512,"whole");
                    if (app.NodbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                    end
                    if (app.dbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)) )); 
                    end
                end
%___________________________hacer fir en modo Low___________________________________________________
                if(strcmp(app.FiltrosDropDown.Value,"low"))
                    app.Wn=(app.FrecunciadecorteHzlowEditField.Value)*2/fs;
                    
                    c=fir1((app.OrdendefiltroEditField.Value),(app.Wn),("low"));
                    
                    [app.h,app.w]=freqz(c,1);
                    if (app.NodbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),abs(app.h)); 
                    end
                    if (app.dbButton.Value)
                        plot (app.UIAxes2,(app.w*fs/(2*pi)),(20*log(abs(app.h)))); 
                    end
                end
            end
        end

        % Button pushed function: EnviarFiltroButton
        function EnviarFiltroButtonPushed(app, event)
            global fs;
            global a;
            global b;
            global c;
            % se rediseña el filtro
            fs = 1000;
            boton_del_aplicacion(app, event);
            
            if(strcmp(app.FormadelfiltroSwitch.Value,"IIR"))
                a1=vpa(a,10);
                b1=vpa(b,10);
                n1=length(a);
                n2=length(b);
                N1=sprintf('%d,',n1);
                N2=sprintf('%d,',n2);
                A=sprintf('%f,',a1);
                B=sprintf('%f,',b1);
                B=B(1:length(B)-1);
                datassent=strcat(N1,N2,A,B);
                tiva = serialport(app.PuertoComEditField.Value, 1000000); %el puerto puede variar entre computadores
                writeline(tiva,datassent);
                clear tiva
            end
            if(strcmp(app.FormadelfiltroSwitch.Value,"FIR"))
                c1=vpa(c,10);
                n3=length(c);
                C=sprintf('%f',c1);
                N3=sprintf('%d,',n3);
                datassent=strcat(N3,C);
                tiva = serialport(app.PuertoComEditField.Value, 1000000); %el puerto puede variar entre computadores
                writeline(tiva,datassent)
                clear tiva  
            end 
            
        end

        % Value changed function: ConexionaTivaCheckBox
        function ConexionaTivaCheckBoxValueChanged(app, event)
            if(app.ConexionaTivaCheckBox.Value)
                app.Ordenmaximodeenvioalativaes4Label.Visible=true;
                app.EnviarFiltroButton.Enable = true;
            else 
                app.Ordenmaximodeenvioalativaes4Label.Visible=false;
                app.EnviarFiltroButton.Enable = false;
                
            end
        end

        % Value changed function: OrdendefiltroEditField
        function OrdendefiltroEditFieldValueChanged(app, event)
            value = app.OrdendefiltroEditField.Value; 
            if(value > 4 && app.ConexionaTivaCheckBox.Value)
                app.OrdendefiltroEditField.Value=4;
            end
        end

        % Value changed function: FormadelfiltroSwitch
        function FormadelfiltroSwitchValueChanged(app, event)
            if(strcmp(app.FormadelfiltroSwitch.Value,"FIR"))
                app.TipodeFiltroDropDown.Enable = false;   
            else 
                app.TipodeFiltroDropDown.Enable = true;
            end
            
        end

        % Button pushed function: AplicarFiltroButton
        function AplicarFiltroButtonPushed(app, event)
            global a;
            global b;
            global c;
            global X1;
                        
            if(strcmp(app.FormadelfiltroSwitch.Value,"IIR"))
                X1=filter(a,b,app.senial);
            end
            if(strcmp(app.FormadelfiltroSwitch.Value,"FIR"))
                  X1=filter(c,1,app.senial);
            end
            n=1:length(X1);
            plot(app.UIAxes4,n,X1);
            
        end

        % Button pushed function: VisualizarSealaguardarButton
        function VisualizarSealaguardarButtonPushed(app, event)
            global X1;
            n=1:length(X1);
            plot(app.UIAxes_2,n,X1);
            n=1:length(app.senial);
            plot(app.UIAxes5,n,app.senial)
            app.senialconf=X1;
            app.senialsinf= app.senial;
        end

        % Button pushed function: ExportardatosdelfiltroButton
        function ExportardatosdelfiltroButtonPushed(app, event)
            global a;
            global b;
            global c;
            if(strcmp(app.FormadelfiltroSwitch.Value,"IIR"))
                app.numerador=a;
                app.denominador=b;
                [file,path]=uiputfile('.mat','Save Data');
                filenamevariables=strcat(file,path);
                save(filenamevariables,app.numerador, app.denominador);
            end
            if(strcmp(app.FormadelfiltroSwitch.Value,"FIR"))
                app.valordeFir1=c;
                [file,path]=uiputfile('.mat','Save Data');
                filenamevariables=strcat(file,path);
                save(filenamevariables,app.valordeFir1);
            end
            
            
        end

        % Button pushed function: GuardarSealButton
        function GuardarSealButtonPushed(app, event)
            [newname,newpath]=uiputfile('.mat','Save Data',app.senial);  
            
            newfile=fullfile(newpath, newname);
            
            variables=strcat(newfile,app.VariablesEditField.Value,'.mat');
            filenamevariables=fullfile(newpath,variables);
            save(filenamevariables,'-mat');
            
%             [newname,newpath]=uiputfile('.mat','Save Data',app.VariablesEditField.Value);
%             newfile=fullfile(newpath, newname);
%             variables=strcat(newname,newpath,'.mat');
%             save(variables,'-mat');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ObtencinDeDatosTab = uitab(app.TabGroup);
            app.ObtencinDeDatosTab.Title = 'Obtención De Datos';
            app.PuertoComEditFieldLabel = uilabel(app.ObtencinDeDatosTab);
            app.PuertoComEditFieldLabel.Text = 'Puerto Com';
            app.PuertoComEditField = uieditfield(app.ObtencinDeDatosTab, 'text');
            app.ConectarButton = uibutton(app.ObtencinDeDatosTab, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.TiempodesenialmuestradasEditFieldLabel = uilabel(app.ObtencinDeDatosTab);
            app.TiempodesenialmuestradasEditFieldLabel.Text = 'Tiempo de senial muestrada (s) ';
            app.TiempodesenialmuestradasEditField = uieditfield(app.ObtencinDeDatosTab, 'numeric');
            app.CargarButton = uibutton(app.ObtencinDeDatosTab, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar ';
            app.TabGroup2 = uitabgroup(app.ObtencinDeDatosTab);
            app.DTiempoTab = uitab(app.TabGroup2);
            app.DTiempoTab.Title = 'D. Tiempo';
            app.UIAxes = uiaxes(app.DTiempoTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            zlabel(app.UIAxes, 'Z')
            app.DFrecuenciaTab = uitab(app.TabGroup2);
            app.DFrecuenciaTab.Title = 'D. Frecuencia';
            app.UIAxes3 = uiaxes(app.DFrecuenciaTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'Frecuencia (Hz)')
            ylabel(app.UIAxes3, 'Magnitud')
            zlabel(app.UIAxes3, 'Z')
            app.DiseodeFiltroTab = uitab(app.TabGroup);
            app.DiseodeFiltroTab.Title = 'Diseño de Filtro';
            app.FrecunciadecorteHzlowEditFieldLabel = uilabel(app.DiseodeFiltroTab);
            app.FrecunciadecorteHzlowEditFieldLabel.Text = 'Frecuncia de corte (Hz) low ';
            app.FrecunciadecorteHzlowEditField = uieditfield(app.DiseodeFiltroTab, 'numeric');
            app.VisualizarfiltroButton = uibutton(app.DiseodeFiltroTab, 'push');
            app.VisualizarfiltroButton.ButtonPushedFcn = createCallbackFcn(app, @boton_del_aplicacion, true);
            app.VisualizarfiltroButton.Text = 'Visualizar filtro';
            app.FrecunciadecorteHzhighEditFieldLabel = uilabel(app.DiseodeFiltroTab);
            app.FrecunciadecorteHzhighEditFieldLabel.Text = 'Frecuncia de corte (Hz) high ';
            app.FrecunciadecorteHzhighEditField = uieditfield(app.DiseodeFiltroTab, 'numeric');
            app.FiltrosDropDownLabel = uilabel(app.DiseodeFiltroTab);
            app.FiltrosDropDownLabel.Text = 'Filtros ';
            app.FiltrosDropDown = uidropdown(app.DiseodeFiltroTab);
            app.FiltrosDropDown.Items = {'...', 'high', 'bandpass', 'Stopband', 'low'};
            app.FiltrosDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltrosDropDownValueChanged, true);
            app.FiltrosDropDown.Value = '...';
            app.FormadelfiltroSwitchLabel = uilabel(app.DiseodeFiltroTab);
            app.FormadelfiltroSwitchLabel.Text = 'Forma del filtro ';
            app.FormadelfiltroSwitch = uiswitch(app.DiseodeFiltroTab, 'rocker');
            app.FormadelfiltroSwitch.Items = {'IIR', 'FIR'};
            app.FormadelfiltroSwitch.ValueChangedFcn = createCallbackFcn(app, @FormadelfiltroSwitchValueChanged, true);
            app.FormadelfiltroSwitch.Value = 'IIR';
            app.OrdendefiltroEditFieldLabel = uilabel(app.DiseodeFiltroTab);
            app.OrdendefiltroEditFieldLabel.Text = 'Orden de filtro';
            app.OrdendefiltroEditField = uieditfield(app.DiseodeFiltroTab, 'numeric');
            app.OrdendefiltroEditField.ValueChangedFcn = createCallbackFcn(app, @OrdendefiltroEditFieldValueChanged, true);
            app.OrdendefiltroEditField.Value = 1;
            app.EjeYButtonGroup = uibuttongroup(app.DiseodeFiltroTab);
            app.EjeYButtonGroup.Title = 'Eje Y';
            app.dbButton = uiradiobutton(app.EjeYButtonGroup);
            app.dbButton.Text = 'db ';
            app.dbButton.Value = true;
            app.NodbButton = uiradiobutton(app.EjeYButtonGroup);
            app.NodbButton.Text = 'No db ';
            app.EnviarFiltroButton = uibutton(app.DiseodeFiltroTab, 'push');
            app.EnviarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarFiltroButtonPushed, true);
            app.EnviarFiltroButton.Text = 'Enviar Filtro ';
            app.AplicarFiltroButton = uibutton(app.DiseodeFiltroTab, 'push');
            app.AplicarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltroButtonPushed, true);
            app.AplicarFiltroButton.Text = 'Aplicar Filtro ';
            app.TipodeFiltroDropDownLabel = uilabel(app.DiseodeFiltroTab);
            app.TipodeFiltroDropDownLabel.Text = 'Tipo de Filtro';
            app.TipodeFiltroDropDown = uidropdown(app.DiseodeFiltroTab);
            app.TipodeFiltroDropDown.Items = {'...', 'Butterwoth', 'Chebyshev', 'Elliptico'};
            app.TipodeFiltroDropDown.Value = '...';
            app.ConexionaTivaCheckBox = uicheckbox(app.DiseodeFiltroTab);
            app.ConexionaTivaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConexionaTivaCheckBoxValueChanged, true);
            app.ConexionaTivaCheckBox.Text = 'Conexion a Tiva ?';
            app.Ordenmaximodeenvioalativaes4Label = uilabel(app.DiseodeFiltroTab);
            app.Ordenmaximodeenvioalativaes4Label.Text = 'Orden maximo de envio a la tiva es 4';
            app.TabGroup3 = uitabgroup(app.DiseodeFiltroTab);
            app.FiltroTab = uitab(app.TabGroup3);
            app.FiltroTab.Title = 'Filtro';
            app.UIAxes2 = uiaxes(app.FiltroTab);
            title(app.UIAxes2, 'Filtro')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SealconfiltroTab = uitab(app.TabGroup3);
            app.SealconfiltroTab.Title = 'Señal con filtro';
            app.UIAxes4 = uiaxes(app.SealconfiltroTab);
            title(app.UIAxes4, 'Señal con filtro')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.EffectosButtonGroup = uibuttongroup(app.DiseodeFiltroTab);
            app.EffectosButtonGroup.Title = 'Effectos';
            app.ningunoButton = uiradiobutton(app.EffectosButtonGroup);
            app.ningunoButton.Text = 'ninguno ';
            app.ningunoButton.Value = true;
            app.EchoButton = uiradiobutton(app.EffectosButtonGroup);
            app.EchoButton.Text = 'Echo';
            app.algoButton = uiradiobutton(app.EffectosButtonGroup);
            app.algoButton.Text = 'algo ';
            app.ExportaDatosTab = uitab(app.TabGroup);
            app.ExportaDatosTab.Title = 'Exporta Datos';
            app.GuardarSealButton = uibutton(app.ExportaDatosTab, 'push');
            app.GuardarSealButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarSealButtonPushed, true);
            app.GuardarSealButton.Text = 'Guardar Señal';
            app.Propiedadesparaguardar___________Label = uilabel(app.ExportaDatosTab);
            app.Propiedadesparaguardar___________Label.Text = '     __________ Propiedades para guardar  ___________';
            app.VisualizarSealaguardarButton = uibutton(app.ExportaDatosTab, 'push');
            app.VisualizarSealaguardarButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarSealaguardarButtonPushed, true);
            app.VisualizarSealaguardarButton.Text = 'Visualizar Señal a guardar';
            app.ExportardatosdelfiltroButton = uibutton(app.ExportaDatosTab, 'push');
            app.ExportardatosdelfiltroButton.ButtonPushedFcn = createCallbackFcn(app, @ExportardatosdelfiltroButtonPushed, true);
            app.ExportardatosdelfiltroButton.Text = 'Exportar datos del filtro';
            app.ecuacionLabel = uilabel(app.ExportaDatosTab);
            app.ecuacionLabel.Text = '';
            app.TabGroup4 = uitabgroup(app.ExportaDatosTab);
            app.SealFiltradaTab = uitab(app.TabGroup4);
            app.SealFiltradaTab.Title = 'Señal Filtrada';
            app.UIAxes_2 = uiaxes(app.SealFiltradaTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SealnoFiltradaTab = uitab(app.TabGroup4);
            app.SealnoFiltradaTab.Title = 'Señal no Filtrada';
            app.UIAxes5 = uiaxes(app.SealnoFiltradaTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRoyectofinal_OA_LJ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end